classdef convolution_animation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConvolutionAnimationUIFigure  matlab.ui.Figure
        ODDLengthLabel                matlab.ui.control.Label
        InitializeButton              matlab.ui.control.Button
        kernelSelectionCheck          matlab.ui.control.CheckBox
        signalSelectionCheck          matlab.ui.control.CheckBox
        KernelSelection               matlab.ui.control.DropDown
        SelectTemplateKernelLabel     matlab.ui.control.Label
        signalSelection               matlab.ui.control.DropDown
        SelectTemplateSignalsLabel    matlab.ui.control.Label
        ChangeYlimitButton            matlab.ui.control.Button
        YUpperLimitEditField          matlab.ui.control.NumericEditField
        YUpperLimitEditFieldLabel     matlab.ui.control.Label
        YLowerLimitEditField          matlab.ui.control.NumericEditField
        YLowerLimitEditFieldLabel     matlab.ui.control.Label
        kerDef                        matlab.ui.control.EditField
        DefineKernelLabel             matlab.ui.control.Label
        sigDef                        matlab.ui.control.EditField
        DefineSignalLabel             matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        AnimationSpeedSlider          matlab.ui.control.Slider
        AnimationSpeedSliderLabel     matlab.ui.control.Label
        ShowtheprocessButton          matlab.ui.control.Button
        PlotConvolutionButton         matlab.ui.control.Button
        PlotKernelButton              matlab.ui.control.Button
        PlotSignalButton              matlab.ui.control.Button
        AnimationPlot                 matlab.ui.control.UIAxes
        ResultPlot                    matlab.ui.control.UIAxes
        KernelPlot                    matlab.ui.control.UIAxes
        SignalPlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        signal % Signal definition provided by User or Template signals used
        kernel % Kernel definition provided by User or Template kernels used
        data4conv % Zero Padded signal data 
        flipped_kernel % flipping the kernel as per convolution definition
        result % result with zero padding
        conv_res % only signal portion of the result        
        nSignal % length of the signal
        nKernel % length of the kernel 
        nConv % length of the result
        half_kernel % (nKernel / 2)
        
    end
    
    methods (Access = public)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConvolutionAnimationUIFigure.Visible = 'off';
            movegui(app.ConvolutionAnimationUIFigure,"center")
            app.ConvolutionAnimationUIFigure.Visible = 'on';
            app.InitializeButtonPushed();
        end

        % Button pushed function: PlotSignalButton
        function PlotSignalButtonPushed(app, event)
            cla(app.SignalPlot)
            if ~app.signalSelectionCheck.Value
                app.signal = str2num(app.sigDef.Value);
            end
            plot(app.SignalPlot,app.signal,'mo-','MarkerFaceColor','m',"MarkerSize",8,"MarkerEdgeColor",'k','LineWidth',3);    
        end

        % Button pushed function: PlotKernelButton
        function PlotKernelButtonPushed(app, event)
            cla(app.KernelPlot)
            if ~app.kernelSelectionCheck.Value
                app.kernel = str2num(app.kerDef.Value);
            end
            plot(app.KernelPlot,app.kernel,'bo-','MarkerFaceColor','b',"MarkerSize",8,"MarkerEdgeColor",'k','LineWidth',3);
        end

        % Button pushed function: PlotConvolutionButton
        function PlotConvolutionButtonPushed(app, event)
            cla(app.ResultPlot)
            
            % align the center of kernel with the beginning of signal
            for j = app.half_kernel+1:app.nConv-app.half_kernel
                temp_data = app.data4conv(j-app.half_kernel:j+app.half_kernel);
                app.result(j) = sum(temp_data.*app.flipped_kernel);
            end
            
            %extract the portion of interest
            app.conv_res = app.result(app.half_kernel+1:end-app.half_kernel);
            plot(app.ResultPlot,app.conv_res,'ro-','MarkerFaceColor','r',"MarkerSize",8,"MarkerEdgeColor",'k','LineWidth',3);
        end

        % Button pushed function: ShowtheprocessButton
        function ShowtheprocessButtonPushed(app, event)
            cla(app.AnimationPlot)
            app.PlotConvolutionButtonPushed();
            
            hold(app.AnimationPlot,"on")
            plot(app.AnimationPlot,app.data4conv,'Color','#7E2F8E',"Marker","o","LineWidth",1);
            
            %These plots'values will be updated
            Kernel_plot = plot(app.AnimationPlot,app.kernel,'bo-',"LineWidth",1);
            res_plot = plot(app.AnimationPlot,app.kernel,'ro-','LineWidth',1);
            centerLine = plot(app.AnimationPlot,[1 1]*(app.half_kernel+1),[-1000 1000],'k--','LineWidth',1);
            
            ylim_max = max(app.conv_res);
            ylim_min = min(app.conv_res);
            set(app.AnimationPlot,'ylim',[ylim_min ylim_max],'xlim',[0 app.nConv+1]);
            plot(app.AnimationPlot,[1 1]*(app.half_kernel+1),[-1000 1000],'k--','LineWidth',1);
            plot(app.AnimationPlot,[1 1]*(app.nConv - app.half_kernel),[-1000 1000],'k--','LineWidth',1);
            hold(app.AnimationPlot,"off")

            legend(app.AnimationPlot,'Signal', 'Kernel(flipped)','Convolution');
            
            
            for j = app.half_kernel+1:app.nConv-app.half_kernel
                temp_data = app.data4conv(j-app.half_kernel:j+app.half_kernel);
                app.result(j) = sum(temp_data.*app.flipped_kernel);
                
                
                set(Kernel_plot,'XData',j-app.half_kernel:j+app.half_kernel,'YData',app.flipped_kernel);
                set(res_plot,'XData',app.half_kernel+1:j,'YData',app.result(app.half_kernel+1:j));
                set(centerLine,'XData',[j j]);
               
                pause(app.AnimationSpeedSlider.Value);
            end
            
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.ConvolutionAnimationUIFigure)
        end

        % Button pushed function: ChangeYlimitButton
        function ChangeYlimitButtonPushed(app, event)
            set(app.AnimationPlot,'ylim',[app.YLowerLimitEditField.Value app.YUpperLimitEditField.Value]);
        end

        % Value changed function: signalSelection
        function signalSelectionValueChanged(app, event)
            value = app.signalSelection.Value;
            if value == "Square"
                app.signal = [zeros(1,15) ones(1,5) zeros(1,15)];              
            elseif value == "Triangle"
                app.signal = [zeros(1,15) 0:5 4:-1:0 zeros(1,15)];
            elseif value == "Delta"
                app.signal = [zeros(1,15) 1 zeros(1,15)];
            elseif value == "Sine"
                app.signal = [zeros(1,15) sin(0:0.5:2*pi) zeros(1,15)];
            end
            app.signal = app.signal / max(app.signal);
            app.PlotSignalButtonPushed();
        end

        % Value changed function: KernelSelection
        function KernelSelectionValueChanged(app, event)
            value = app.KernelSelection.Value;
            if value == "Square"
                app.kernel = [zeros(1,5) ones(1,5) zeros(1,5)];               
            elseif value == "Triangle"
                app.kernel = [0:5 4:-1:0];
            elseif value == "Delta"
                app.kernel = [zeros(1,5) 1 zeros(1,5)];
            elseif value == "Sine"
                app.kernel = [sin(0:0.5:2*pi)];
            end
            app.kernel = app.kernel / max(app.kernel);
            app.PlotKernelButtonPushed();
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            %How to define signal and kernel
                       
            if app.signalSelectionCheck.Value
                %Select from templates
                app.signalSelectionValueChanged();
            else
                %Select from user
                app.signal = str2num(app.sigDef.Value);
            end
            
            if app.kernelSelectionCheck.Value
                %Select from templates
                app.KernelSelectionValueChanged();
            else
                %Select from user
                app.kernel = str2num(app.kerDef.Value);
            end
            
            app.nSignal = length(app.signal);
            app.nKernel = length(app.kernel);
            app.nConv = app.nSignal + app.nKernel - 1;
            
            app.result = zeros(1,app.nConv);
            
            app.half_kernel = floor(app.nKernel/2);
            app.data4conv = [zeros(1,app.half_kernel) app.signal zeros(1,app.half_kernel)];            
            app.flipped_kernel = app.kernel(end:-1:1); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConvolutionAnimationUIFigure = uifigure('Visible', 'off');
            app.ConvolutionAnimationUIFigure.Name = 'Convolution Animation';
            app.SignalPlot = uiaxes(app.ConvolutionAnimationUIFigure);
            title(app.SignalPlot, 'Signal')
            app.SignalPlot.LineStyleOrder = {'- o'};
            app.KernelPlot = uiaxes(app.ConvolutionAnimationUIFigure);
            title(app.KernelPlot, 'Kernel')
            app.KernelPlot.LineStyleOrder = {'- o'};
            app.ResultPlot = uiaxes(app.ConvolutionAnimationUIFigure);
            title(app.ResultPlot, 'Result of the Convolution')
            app.ResultPlot.LineStyleOrder = {'- o'};
            app.AnimationPlot = uiaxes(app.ConvolutionAnimationUIFigure);
            title(app.AnimationPlot, 'Animation of the Process')
            app.AnimationPlot.LineStyleOrder = {'- o'};
            app.PlotSignalButton = uibutton(app.ConvolutionAnimationUIFigure, 'push');
            app.PlotSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSignalButtonPushed, true);
            app.PlotSignalButton.Text = 'Plot Signal';
            app.PlotKernelButton = uibutton(app.ConvolutionAnimationUIFigure, 'push');
            app.PlotKernelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotKernelButtonPushed, true);
            app.PlotKernelButton.Text = 'Plot Kernel';
            app.PlotConvolutionButton = uibutton(app.ConvolutionAnimationUIFigure, 'push');
            app.PlotConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotConvolutionButtonPushed, true);
            app.PlotConvolutionButton.Text = 'Plot Convolution';
            app.ShowtheprocessButton = uibutton(app.ConvolutionAnimationUIFigure, 'push');
            app.ShowtheprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtheprocessButtonPushed, true);
            app.ShowtheprocessButton.Text = 'Show the process';
            app.AnimationSpeedSliderLabel = uilabel(app.ConvolutionAnimationUIFigure);
            app.AnimationSpeedSliderLabel.Text = 'Animation Speed';
            app.AnimationSpeedSlider = uislider(app.ConvolutionAnimationUIFigure);
            app.AnimationSpeedSlider.Limits = [0.1 1];
            app.AnimationSpeedSlider.Value = 0.5;
            app.CloseButton = uibutton(app.ConvolutionAnimationUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DefineSignalLabel = uilabel(app.ConvolutionAnimationUIFigure);
            app.DefineSignalLabel.Text = 'Define Signal:';
            app.sigDef = uieditfield(app.ConvolutionAnimationUIFigure, 'text');
            app.sigDef.Value = '[zeros(1,5) ones(1,5) zeros(1,5)]';
            app.DefineKernelLabel = uilabel(app.ConvolutionAnimationUIFigure);
            app.DefineKernelLabel.Text = 'Define Kernel:';
            app.kerDef = uieditfield(app.ConvolutionAnimationUIFigure, 'text');
            app.kerDef.Value = '[ 4:-1:0 ]/max([ 4:-1:0 ])';
            app.YLowerLimitEditFieldLabel = uilabel(app.ConvolutionAnimationUIFigure);
            app.YLowerLimitEditFieldLabel.Text = 'Y Lower Limit';
            app.YLowerLimitEditField = uieditfield(app.ConvolutionAnimationUIFigure, 'numeric');
            app.YUpperLimitEditFieldLabel = uilabel(app.ConvolutionAnimationUIFigure);
            app.YUpperLimitEditFieldLabel.Text = 'Y Upper Limit';
            app.YUpperLimitEditField = uieditfield(app.ConvolutionAnimationUIFigure, 'numeric');
            app.YUpperLimitEditField.Value = 10;
            app.ChangeYlimitButton = uibutton(app.ConvolutionAnimationUIFigure, 'push');
            app.ChangeYlimitButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeYlimitButtonPushed, true);
            app.ChangeYlimitButton.Text = 'Change Y limit';
            app.SelectTemplateSignalsLabel = uilabel(app.ConvolutionAnimationUIFigure);
            app.SelectTemplateSignalsLabel.Text = 'Select Template Signals';
            app.signalSelection = uidropdown(app.ConvolutionAnimationUIFigure);
            app.signalSelection.Items = {'Square', 'Sine', 'Delta', 'Triangle'};
            app.signalSelection.ValueChangedFcn = createCallbackFcn(app, @signalSelectionValueChanged, true);
            app.signalSelection.Value = 'Square';
            app.SelectTemplateKernelLabel = uilabel(app.ConvolutionAnimationUIFigure);
            app.SelectTemplateKernelLabel.Text = 'Select Template Kernel';
            app.KernelSelection = uidropdown(app.ConvolutionAnimationUIFigure);
            app.KernelSelection.Items = {'Square', 'Sine', 'Delta', 'Triangle'};
            app.KernelSelection.ValueChangedFcn = createCallbackFcn(app, @KernelSelectionValueChanged, true);
            app.KernelSelection.Value = 'Square';
            app.signalSelectionCheck = uicheckbox(app.ConvolutionAnimationUIFigure);
            app.signalSelectionCheck.Text = '';
            app.kernelSelectionCheck = uicheckbox(app.ConvolutionAnimationUIFigure);
            app.kernelSelectionCheck.Text = '';
            app.InitializeButton = uibutton(app.ConvolutionAnimationUIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.ODDLengthLabel = uilabel(app.ConvolutionAnimationUIFigure);
            app.ODDLengthLabel.Text = '[ODD Length]';
            % Show the figure after all components are created
            app.ConvolutionAnimationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convolution_animation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConvolutionAnimationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConvolutionAnimationUIFigure)
        end
    end
end