classdef denoise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleSignalDenoiserUIFigure  matlab.ui.Figure
        ClearPlotButton               matlab.ui.control.Button
        ExportDataButton              matlab.ui.control.Button
        UpdateButton                  matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        ShowAllButton                 matlab.ui.control.Button
        MeanFilterButton              matlab.ui.control.Button
        GuassianDenoiseButton         matlab.ui.control.Button
        PlotDataButton                matlab.ui.control.Button
        ImportDataButton              matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        fwhmField                     matlab.ui.control.NumericEditField
        FullWidthAtHalfPowerLabel     matlab.ui.control.Label
        WindowSizeField               matlab.ui.control.NumericEditField
        WindowSizekkkLabel            matlab.ui.control.Label
        TimeEndField                  matlab.ui.control.NumericEditField
        TimeEndLabel                  matlab.ui.control.Label
        TimeStartField                matlab.ui.control.NumericEditField
        TimeBeginLabel                matlab.ui.control.Label
        SamplingRateField             matlab.ui.control.NumericEditField
        SamplingRateHzLabel           matlab.ui.control.Label
        Output                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        signal
        time
        ntime
        guass_win
        srate %Hz
        fwhm %full width half maximum
        winSize % window size -30 to 30
        guass_filtered_signal
        mean_filtered_signal
    end
    
    methods (Access = public)
        
        function [] = guass_filter(app)
            k = app.winSize;
            app.guass_filtered_signal = zeros(1,app.ntime);
            for i = k+1:app.ntime-k-1
                app.guass_filtered_signal(i) = dot(app.signal(i-k:i+k),app.guass_win);                
            end
        end
        
        function [] = mean_filter(app)
            k = app.winSize;
            app.mean_filtered_signal = zeros(1,app.ntime);
            for i=k+1:app.ntime-k-1
                app.mean_filtered_signal(i) = mean(app.signal(i-k:i+k)); 
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Default(app)
            app.SimpleSignalDenoiserUIFigure.Visible = 'off';
            movegui(app.SimpleSignalDenoiserUIFigure,"center")
            app.SimpleSignalDenoiserUIFigure.Visible = 'on';
            
            app.srate = 1000;
            app.time = 0:1/app.srate:3;
            app.ntime = length(app.time);
            
            %generating a random signal
            pole = 10; % for interpolating through this amount of random values 
            noise_amp = 2;
            ampl = interp1(rand(1,pole)*30,linspace(1,pole,app.ntime));
            noise = noise_amp* randn(size(app.time));
            app.signal = ampl + noise;
            
            %setting default window paramters
            app.winSize = 30;
            app.fwhm = 25/app.srate;
            gtime = (-app.winSize:app.winSize)/app.srate;
            app.guass_win = exp(-(4*log(2)*gtime.^2)/app.fwhm^2);
            %normalize the window
            app.guass_win = app.guass_win / sum(app.guass_win);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            filename = uigetfile();
            drawnow;
            figure(app.SimpleSignalDenoiserUIFigure);
            signalData = readtable(filename);
            app.signal = signalData.Var2;
            app.time = signalData.Var1;
            
            
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            cla(app.Output);
            plot(app.Output,app.time,app.signal);
            set(app.Output,'ylim',[min(app.signal) max(app.signal)]);
        end

        % Button pushed function: GuassianDenoiseButton
        function GuassianDenoiseButtonPushed(app, event)
            app.guass_filter();
            cla(app.Output);
            plot(app.Output,app.time,app.guass_filtered_signal,'LineWidth',2,'Color','#c21111');
        end

        % Button pushed function: MeanFilterButton
        function MeanFilterButtonPushed(app, event)
            app.mean_filter();
            cla(app.Output);
            plot(app.Output,app.time,app.mean_filtered_signal,'LineWidth',2,'Color','#1a940f');
        end

        % Button pushed function: ShowAllButton
        function ShowAllButtonPushed(app, event)
            app.guass_filter();
            app.mean_filter();
            
            cla(app.Output);
            plot(app.Output,app.time,app.signal,'color','#0f32bd');
            hold(app.Output,"on")
            plot(app.Output,app.time,app.guass_filtered_signal,'LineWidth',2,'Color','#c21111');
            plot(app.Output,app.time,app.mean_filtered_signal,'linew',2,'Color','#1a940f');
            hold(app.Output,"off");
%             legend(app.Output,["Input Signal";"Guassian Smoothed Signal";"Mean Filtered Signal"]);

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            %update time
            app.srate = app.SamplingRateField.Value;
            t_begin = app.TimeStartField.Value;
            t_end = app.TimeEndField.Value;
            set(app.Output,'xlim',[t_begin t_end]);
            

            %update window
            app.winSize = app.WindowSizeField.Value;
            app.fwhm = app.fwhmField.Value/app.srate;            
            gtime = (-app.winSize:app.winSize)/app.srate;
            app.guass_win = exp(-(4*log(2)*gtime.^2)/app.fwhm^2);
            app.guass_win = app.guass_win / sum(app.guass_win);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            
            data_guass_filtered = [app.time(:) app.guass_filtered_signal(:)];
            data_mean_filtered = [app.time(:) app.mean_filtered_signal(:)];
            
            writematrix(data_guass_filtered,'denoised_signal_guassian.csv');
            writematrix(data_mean_filtered,'denoised_signal_mean.csv');
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.Output);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleSignalDenoiserUIFigure = uifigure('Visible', 'off');
            app.SimpleSignalDenoiserUIFigure.Name = 'Simple Signal Denoiser';
            app.Output = uiaxes(app.SimpleSignalDenoiserUIFigure);
            title(app.Output, 'Filtered Signal')
            xlabel(app.Output, 'Time(s)')
            ylabel(app.Output, 'Amplitude')
            app.Output.XTickLabel = '';
            app.Output.YTickLabel = '';
            app.Panel = uipanel(app.SimpleSignalDenoiserUIFigure);
            app.SamplingRateHzLabel = uilabel(app.Panel);
            app.SamplingRateHzLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateField = uieditfield(app.Panel, 'numeric');
            app.SamplingRateField.Value = 1000;
            app.TimeBeginLabel = uilabel(app.Panel);
            app.TimeBeginLabel.Text = 'Time(Begin)';
            app.TimeStartField = uieditfield(app.Panel, 'numeric');
            app.TimeEndLabel = uilabel(app.Panel);
            app.TimeEndLabel.Text = 'Time(End)';
            app.TimeEndField = uieditfield(app.Panel, 'numeric');
            app.TimeEndField.Value = 3;
            app.WindowSizekkkLabel = uilabel(app.Panel);
            app.WindowSizekkkLabel.Text = 'Window Size, k (-k:k)';
            app.WindowSizeField = uieditfield(app.Panel, 'numeric');
            app.WindowSizeField.Value = 30;
            app.FullWidthAtHalfPowerLabel = uilabel(app.Panel);
            app.FullWidthAtHalfPowerLabel.Text = 'Full Width At Half Power';
            app.fwhmField = uieditfield(app.Panel, 'numeric');
            app.fwhmField.Value = 30;
            app.ImportDataButton = uibutton(app.SimpleSignalDenoiserUIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.PlotDataButton = uibutton(app.SimpleSignalDenoiserUIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.GuassianDenoiseButton = uibutton(app.SimpleSignalDenoiserUIFigure, 'push');
            app.GuassianDenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GuassianDenoiseButtonPushed, true);
            app.GuassianDenoiseButton.Text = 'Guassian Denoise';
            app.MeanFilterButton = uibutton(app.SimpleSignalDenoiserUIFigure, 'push');
            app.MeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanFilterButtonPushed, true);
            app.MeanFilterButton.Tooltip = {'Enable Tools'};
            app.MeanFilterButton.Text = 'Mean Filter';
            app.ShowAllButton = uibutton(app.SimpleSignalDenoiserUIFigure, 'push');
            app.ShowAllButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButtonPushed, true);
            app.ShowAllButton.Text = 'Show All';
            app.CloseButton = uibutton(app.SimpleSignalDenoiserUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UpdateButton = uibutton(app.SimpleSignalDenoiserUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ExportDataButton = uibutton(app.SimpleSignalDenoiserUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ClearPlotButton = uibutton(app.SimpleSignalDenoiserUIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.SimpleSignalDenoiserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = denoise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleSignalDenoiserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Default)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleSignalDenoiserUIFigure)
        end
    end
end