classdef filter_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PassiveFilterResponseUIFigure  matlab.ui.Figure
        CLabel                         matlab.ui.control.Label
        HLabel                         matlab.ui.control.Label
        ohmLabel                       matlab.ui.control.Label
        BandwidthHzLabel               matlab.ui.control.Label
        BandwidthField                 matlab.ui.control.NumericEditField
        mHLabel                        matlab.ui.control.Label
        uCLabel                        matlab.ui.control.Label
        KohmLabel                      matlab.ui.control.Label
        ResetPlotButton                matlab.ui.control.Button
        XmaxEditFieldLabel             matlab.ui.control.Label
        XmaxEditField                  matlab.ui.control.NumericEditField
        XminEditFieldLabel             matlab.ui.control.Label
        XminEditField                  matlab.ui.control.NumericEditField
        CenterFrequencyHzLabel         matlab.ui.control.Label
        CenterFrequencyField           matlab.ui.control.NumericEditField
        HalfPowerFrequency2HzLabel     matlab.ui.control.Label
        CuttOffFrequency2Field         matlab.ui.control.NumericEditField
        HalfPowerFrequency1HzLabel     matlab.ui.control.Label
        CuttOffFrequency1Field         matlab.ui.control.NumericEditField
        LEditFieldLabel                matlab.ui.control.Label
        L                              matlab.ui.control.NumericEditField
        CEditFieldLabel                matlab.ui.control.Label
        C                              matlab.ui.control.NumericEditField
        REditFieldLabel                matlab.ui.control.Label
        R                              matlab.ui.control.NumericEditField
        FilterTypeDropDownLabel        matlab.ui.control.Label
        SelectFilter                   matlab.ui.control.DropDown
        Image                          matlab.ui.control.Image
        PlotFrequencyResponseButton    matlab.ui.control.Button
        Rslider                        matlab.ui.control.Slider
        Cslider                        matlab.ui.control.Slider
        Lslider                        matlab.ui.control.Slider
        valueToUse                     matlab.ui.container.ButtonGroup
        UseSliderValue                 matlab.ui.control.RadioButton
        UseTextFieldValue              matlab.ui.control.RadioButton
        ChangexLimButton               matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        FreqResponcePlot               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        freq % Description
        omega
        current_circuit % Description
        cuttFreq1
        cuttFreq2
        centerFreq
        bandwidth
    end
    
    methods (Access = public)
        
        function [] = PlotBandPass(app,Rval,Cval,Lval)            
            H = (Rval)./(Rval+1i.*(app.omega.*Lval - 1./(app.omega.*Cval)));
            app.plotResponse(H,1);           
        end
        
        function [] = PlotBandNotch(app,Rval,Cval,Lval)
            H = (Rval)./(Rval+1i.*(app.omega.*Lval - 1./(app.omega.*Cval)));
            app.plotResponse(H,2);
        end
        
        function [] = PlotLowPass(app,Rval,Cval)
            H = 1./(1+(1i.*app.omega.*Rval.*Cval));
            app.plotResponse(H,3);
        end
        
        function [] = PlotHighPass(app,Rval,Cval)
            H = (1i.*app.omega.*Rval.*Cval)./(1+(1i.*app.omega.*Rval.*Cval));
            app.plotResponse(H,4)
        end
        
        function [] = plotResponse(app,H,type)
            H_mag = abs(H);
            max_H_mag = max(H_mag);
            normalized_H_mag = H_mag/max_H_mag;
            
            % |H(w)|notch + |H(w)|pass = 1
            % Using this to compute |H(w)|notch
            if type == 2
                normalized_H_mag = 1 - normalized_H_mag;
            end

            semilogx(app.FreqResponcePlot,app.freq,normalized_H_mag,'LineWidth',5,"Color",'#A2142F');
            set(app.FreqResponcePlot,'xLim',[app.freq(1) app.freq(end)]);
            
            
            if type == 1 || type == 2
                cuttFreq1Idx = dsearchn(normalized_H_mag',1/sqrt(2));
                app.cuttFreq1 =  app.freq(cuttFreq1Idx);
                if type == 1
                    centerFreqIdx = dsearchn(normalized_H_mag',1);
                    app.centerFreq =  app.freq(centerFreqIdx);
                else
                    centerFreqIdx = dsearchn(normalized_H_mag',0);
                    app.centerFreq = app.freq(centerFreqIdx);       
                end
                
                % wc = sqrt(w1,w2)
                app.cuttFreq2 =  app.centerFreq^2/app.cuttFreq1;
                
                % Making sure first cuttoff frequency is smaller than
                % second
                if app.cuttFreq2 < app.cuttFreq1
                    temp = app.cuttFreq2;
                    app.cuttFreq2 = app.cuttFreq1;
                    app.cuttFreq1 = temp;
                end
                
                %update field values
                app.CuttOffFrequency1Field.Value = app.cuttFreq1;
                app.CuttOffFrequency2Field.Value = app.cuttFreq2;
                app.CenterFrequencyField.Value = app.centerFreq;
                
                app.bandwidth = app.cuttFreq2 - app.cuttFreq1;
                app.BandwidthField.Value = app.bandwidth;
                
               hold(app.FreqResponcePlot,"on");
               semilogx(app.FreqResponcePlot,app.cuttFreq1,1/sqrt(2),'ko',"MarkerSize",15,"MarkerFaceColor",'k');
               semilogx(app.FreqResponcePlot,app.cuttFreq2,1/sqrt(2),'ko',"MarkerSize",15,"MarkerFaceColor",'k');
               semilogx(app.FreqResponcePlot,[app.cuttFreq1 app.cuttFreq2],[1/sqrt(2) 1/sqrt(2)],'k--',"LineWidth",2);
               hold(app.FreqResponcePlot,"off");
            end
            
            if type == 3 || type == 4
                cuttFreq1Idx = dsearchn(normalized_H_mag',1/sqrt(2));
                app.cuttFreq1 =  app.freq(cuttFreq1Idx);
                app.bandwidth = 0;
                app.CuttOffFrequency1Field.Value = app.cuttFreq1;
                app.CuttOffFrequency2Field.Value = 0;
                app.CenterFrequencyField.Value = 0;
                
                hold(app.FreqResponcePlot,"on");
                semilogx(app.FreqResponcePlot,app.cuttFreq1,1/sqrt(2),'ko',"MarkerSize",15,"MarkerFaceColor",'k');
                semilogx(app.FreqResponcePlot,[app.freq(1) app.cuttFreq1],[1/sqrt(2) 1/sqrt(2)],'k--',"LineWidth",2);
                hold(app.FreqResponcePlot,"off");
            end
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initilialize(app)
            app.PassiveFilterResponseUIFigure.Visible = 'off';
            movegui(app.PassiveFilterResponseUIFigure,"center")
            app.PassiveFilterResponseUIFigure.Visible = 'on';
            app.Image.ImageSource = 'circuit_figures/bandPass.PNG';
            app.freq = logspace(-15,15,100000);
            app.omega = 2*pi*app.freq;
            app.current_circuit = "Band Pass Filter";
        end

        % Value changed function: SelectFilter
        function SelectFilterCallback(app, event)
            value = num2str(app.SelectFilter.Value);
            if value == "Band Pass Filter"
                app.Image.ImageSource = 'circuit_figures/bandPass.PNG';
            elseif value == "Band Notch Filter"
                app.Image.ImageSource = 'circuit_figures/bandNotch.PNG';
            elseif value == "Low Pass Filter"
                app.Image.ImageSource = 'circuit_figures/lowPass.PNG';
            else
                app.Image.ImageSource = 'circuit_figures/highPass.PNG';
            end
            app.current_circuit = value;
        end

        % Button pushed function: PlotFrequencyResponseButton
        function PlotFrequencyResponseButtonPushed(app, event)

            value = app.UseTextFieldValue.Value;
            if value == 0
                Rval = app.Rslider.Value*1000;
                Cval = app.Cslider.Value * 1e-6;
                Lval = app.Lslider.Value * 1e-3;
            else
                Rval = app.R.Value;
                Cval = app.C.Value;
                Lval = app.L.Value;
            end
            app.current_circuit = num2str(app.current_circuit);
            
            if app.current_circuit == "Band Pass Filter"
                app.PlotBandPass(Rval,Cval,Lval);
            elseif app.current_circuit == "Band Notch Filter"
                app.PlotBandNotch(Rval,Cval,Lval);
            elseif app.current_circuit == "Low Pass Filter"
                app.PlotLowPass(Rval,Cval);
            else
                app.PlotHighPass(Rval,Cval);
            end
        end

        % Button pushed function: ChangexLimButton
        function ChangexLimButtonPushed(app, event)
            app.FreqResponcePlot.XLim = [app.XminEditField.Value app.XmaxEditField.Value];
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ResetPlotButton
        function ResetPlotButtonPushed(app, event)
            cla(app.FreqResponcePlot);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PassiveFilterResponseUIFigure = uifigure('Visible', 'off');
            app.PassiveFilterResponseUIFigure.Name = 'Passive Filter Response';
            app.FreqResponcePlot = uiaxes(app.PassiveFilterResponseUIFigure);
            title(app.FreqResponcePlot, 'Frequency Response of the selected Filter Circuit')
            xlabel(app.FreqResponcePlot, 'Frequency( Hz)')
            ylabel(app.FreqResponcePlot, {'| H(w) |'; ''})
            zlabel(app.FreqResponcePlot, 'Z')
            app.Panel = uipanel(app.PassiveFilterResponseUIFigure);
            app.CloseButton = uibutton(app.PassiveFilterResponseUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ChangexLimButton = uibutton(app.PassiveFilterResponseUIFigure, 'push');
            app.ChangexLimButton.ButtonPushedFcn = createCallbackFcn(app, @ChangexLimButtonPushed, true);
            app.ChangexLimButton.Text = 'Change xLim';
            app.valueToUse = uibuttongroup(app.PassiveFilterResponseUIFigure);
            app.UseTextFieldValue = uiradiobutton(app.valueToUse);
            app.UseTextFieldValue.Text = 'Use Text Field Value';
            app.UseTextFieldValue.Value = true;
            app.UseSliderValue = uiradiobutton(app.valueToUse);
            app.UseSliderValue.Text = 'Use Slider Value';
            app.Lslider = uislider(app.PassiveFilterResponseUIFigure);
            app.Lslider.Limits = [0.1 100];
            app.Lslider.Value = 50;
            app.Cslider = uislider(app.PassiveFilterResponseUIFigure);
            app.Cslider.Limits = [0.01 1];
            app.Cslider.Value = 1;
            app.Rslider = uislider(app.PassiveFilterResponseUIFigure);
            app.Rslider.Limits = [1 100];
            app.Rslider.MajorTicks = [1 21 41 61 81 100];
            app.Rslider.Value = 50;
            app.PlotFrequencyResponseButton = uibutton(app.PassiveFilterResponseUIFigure, 'push');
            app.PlotFrequencyResponseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFrequencyResponseButtonPushed, true);
            app.PlotFrequencyResponseButton.Text = 'Plot  Frequency Response';
            app.Image = uiimage(app.PassiveFilterResponseUIFigure);
            app.SelectFilter = uidropdown(app.PassiveFilterResponseUIFigure);
            app.SelectFilter.Items = {'Band Pass Filter', 'Band Notch Filter', 'Low Pass Filter', 'High Pass Filter'};
            app.SelectFilter.ValueChangedFcn = createCallbackFcn(app, @SelectFilterCallback, true);
            app.SelectFilter.Value = 'Band Pass Filter';
            app.FilterTypeDropDownLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.R = uieditfield(app.PassiveFilterResponseUIFigure, 'numeric');
            app.R.Value = 200000;
            app.REditFieldLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.REditFieldLabel.Text = 'R';
            app.C = uieditfield(app.PassiveFilterResponseUIFigure, 'numeric');
            app.C.Value = 1e-06;
            app.CEditFieldLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.L = uieditfield(app.PassiveFilterResponseUIFigure, 'numeric');
            app.L.Value = 0.05;
            app.LEditFieldLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.CuttOffFrequency1Field = uieditfield(app.PassiveFilterResponseUIFigure, 'numeric');
            app.HalfPowerFrequency1HzLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.HalfPowerFrequency1HzLabel.Text = 'Half Power Frequency 1 (Hz)';
            app.CuttOffFrequency2Field = uieditfield(app.PassiveFilterResponseUIFigure, 'numeric');
            app.HalfPowerFrequency2HzLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.HalfPowerFrequency2HzLabel.Text = 'Half Power Frequency 2 (Hz)';
            app.CenterFrequencyField = uieditfield(app.PassiveFilterResponseUIFigure, 'numeric');
            app.CenterFrequencyHzLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.CenterFrequencyHzLabel.Text = 'Center Frequency (Hz)';
            app.XminEditField = uieditfield(app.PassiveFilterResponseUIFigure, 'numeric');
            app.XminEditFieldLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XmaxEditField = uieditfield(app.PassiveFilterResponseUIFigure, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.ResetPlotButton = uibutton(app.PassiveFilterResponseUIFigure, 'push');
            app.ResetPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlotButtonPushed, true);
            app.ResetPlotButton.Text = 'Reset Plot';
            app.KohmLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.KohmLabel.Text = 'Kohm';
            app.uCLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.uCLabel.Text = 'uC';
            app.mHLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.mHLabel.Text = 'mH';
            app.BandwidthField = uieditfield(app.PassiveFilterResponseUIFigure, 'numeric');
            app.BandwidthHzLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.BandwidthHzLabel.Text = 'Bandwidth (Hz)';
            app.ohmLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.ohmLabel.Text = 'ohm';
            app.HLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.HLabel.Text = 'H';
            app.CLabel = uilabel(app.PassiveFilterResponseUIFigure);
            app.CLabel.Text = 'C';
            % Show the figure after all components are created
            app.PassiveFilterResponseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filter_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PassiveFilterResponseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Initilialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PassiveFilterResponseUIFigure)
        end
    end
end