classdef fourier_transform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierTransformUIFigure  matlab.ui.Figure
        HzLabel_2                 matlab.ui.control.Label
        HzLabel                   matlab.ui.control.Label
        ThresholdforUpdatingFrequencyPlotButton  matlab.ui.control.Button
        freqLimitField            matlab.ui.control.NumericEditField
        UpdateSamplingRateButton  matlab.ui.control.Button
        samplingRateField         matlab.ui.control.NumericEditField
        SignalLabel               matlab.ui.control.Label
        ApplyButton               matlab.ui.control.Button
        SignalEditField           matlab.ui.control.EditField
        CloseButton               matlab.ui.control.Button
        ComputeButton             matlab.ui.control.Button
        frequency                 matlab.ui.control.UIAxes
        reconstrudedPlot          matlab.ui.control.UIAxes
        timeSignal                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal;
        time;
        n;
        fCoeff;
        srate;
        hz
        normalized_time;
        t_begin = 0;
        t_end = 3;
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app)

            
            app.FourierTransformUIFigure.Visible = 'off';
            movegui(app.FourierTransformUIFigure,"center")
            app.FourierTransformUIFigure.Visible = 'on';
            
            app.srate = 1000; %Hz
            app.time = app.t_begin:1/app.srate:app.t_end;
            app.n = length(app.time);

            app.signal = (1+sin(2*pi*12*app.time)) .* cos(sin(2*pi*25*app.time)+app.time);
        
            plot(app.timeSignal,app.time,app.signal,'k','LineWidth',2);
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            cla(app.frequency);
            cla(app.timeSignal);
            cla(app.reconstrudedPlot);
            

            plot(app.timeSignal,app.time,app.signal,'k','LineWidth',2);
            
            app.fCoeff = zeros(size(app.signal));
            app.normalized_time = (0:app.n-1)/app.n;
            for k = 1:app.n
                complex_exp = exp(-1i*2*pi.*app.normalized_time*(k-1));
                app.fCoeff(k) = sum(app.signal.*complex_exp) / app.n;
            end
            
            app.hz = linspace(0,app.srate,app.n);
            ampl = abs(app.fCoeff);
            ampl(2:end)  = 2*ampl(2:end);
            max_ampl = max(ampl);
            
            
            frequency_plot_handle = plot(app.frequency,0,'Color','#D95319',"LineWidth",2);
            set(app.frequency,'xlim',app.hz([1 end]),'ylim',[0 max_ampl]);    
            
            reconstructed_signal = zeros(size(app.signal));
            reconstruced_plot_handle = plot(app.reconstrudedPlot,app.time,reconstructed_signal,'Color','#D95319',"LineWidth",1);                                
            
            
            f_lower_limit = dsearchn(app.hz',app.freqLimitField.Value);
            f_upper_limit = dsearchn(app.hz',app.srate - app.freqLimitField.Value);
            
            for k = 1:app.n
                complex_exp = exp(1i*2*pi*app.normalized_time*(k-1));
                reconstructed_signal = reconstructed_signal + app.fCoeff(k)*complex_exp;
                if k < f_lower_limit || k> f_upper_limit               
                    set(reconstruced_plot_handle,'YData',real(reconstructed_signal));
                    set(frequency_plot_handle,'XData',app.hz(1:k),'YData',ampl(1:k));
                    pause(0.05);
                end
                
            end


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Callback function
        function SquareWaveButtonPushed(app, event)
          app.signal = zeros(size(app.time));
          app.signal(app.n/4:app.n*3/4) = 1;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            f = str2func(app.SignalEditField.Value);
            app.signal = f(app.time);
            plot(app.timeSignal,app.time,app.signal,'k','LineWidth',2);
        end

        % Button pushed function: UpdateSamplingRateButton
        function UpdateSamplingRateButtonPushed(app, event)
            f = str2func(app.SignalEditField.Value);
            app.srate = app.samplingRateField.Value; %Hz
            app.time = 0:1/app.srate:2;
            app.n = length(app.time);
            app.signal = f(app.time);
            plot(app.timeSignal,app.time,app.signal,'k','LineWidth',2);
        end

        % Button pushed function: 
        % ThresholdforUpdatingFrequencyPlotButton
        function ThresholdforUpdatingFrequencyPlotButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourierTransformUIFigure = uifigure('Visible', 'off');
            app.FourierTransformUIFigure.Name = 'Fourier Transform';
            app.timeSignal = uiaxes(app.FourierTransformUIFigure);
            title(app.timeSignal, {'Original Time Signal'; ''})
            xlabel(app.timeSignal, 'Time (s)')
            ylabel(app.timeSignal, 'Amplitude ')
            app.timeSignal.XTick = [];
            app.reconstrudedPlot = uiaxes(app.FourierTransformUIFigure);
            title(app.reconstrudedPlot, {'Reconstructed Signal'; ''})
            xlabel(app.reconstrudedPlot, 'Time (s)')
            ylabel(app.reconstrudedPlot, 'Amplitude ')
            app.reconstrudedPlot.XTick = [];
            app.frequency = uiaxes(app.FourierTransformUIFigure);
            title(app.frequency, {'Magnitude Spectrum'; ''})
            xlabel(app.frequency, 'Frequency (Hz)')
            ylabel(app.frequency, 'Amplitude ')
            app.frequency.XTick = [];
            app.frequency.XTickLabel = '';
            app.ComputeButton = uibutton(app.FourierTransformUIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.CloseButton = uibutton(app.FourierTransformUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SignalEditField = uieditfield(app.FourierTransformUIFigure, 'text');
            app.SignalEditField.Value = '@(t) (1+sin(2*pi*12*t)) .* cos(sin(2*pi*25*t)+t)';
            app.ApplyButton = uibutton(app.FourierTransformUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SignalLabel = uilabel(app.FourierTransformUIFigure);
            app.SignalLabel.Text = 'Signal';
            app.samplingRateField = uieditfield(app.FourierTransformUIFigure, 'numeric');
            app.samplingRateField.Value = 1000;
            app.UpdateSamplingRateButton = uibutton(app.FourierTransformUIFigure, 'push');
            app.UpdateSamplingRateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSamplingRateButtonPushed, true);
            app.UpdateSamplingRateButton.Text = 'Update Sampling Rate';
            app.freqLimitField = uieditfield(app.FourierTransformUIFigure, 'numeric');
            app.freqLimitField.Value = 50;
            app.ThresholdforUpdatingFrequencyPlotButton = uibutton(app.FourierTransformUIFigure, 'push');
            app.ThresholdforUpdatingFrequencyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdforUpdatingFrequencyPlotButtonPushed, true);
            app.ThresholdforUpdatingFrequencyPlotButton.Text = 'Threshold for Updating Frequency Plot  ';
            app.HzLabel = uilabel(app.FourierTransformUIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.FourierTransformUIFigure);
            app.HzLabel_2.Text = 'Hz';
            % Show the figure after all components are created
            app.FourierTransformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fourier_transform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierTransformUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierTransformUIFigure)
        end
    end
end