classdef img_convolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageFilterUIFigure           matlab.ui.Figure
        FilteredImageLabel            matlab.ui.control.Label
        InputImageLabel               matlab.ui.control.Label
        GrayScaleImageLabel           matlab.ui.control.Label
        ThresholdValue                matlab.ui.control.NumericEditField
        CloseButton                   matlab.ui.control.Button
        ResetImageButton              matlab.ui.control.Button
        ThresholdValueEditFieldLabel  matlab.ui.control.Label
        BinarizeButton                matlab.ui.control.Button
        SepiaButton                   matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        SelectKernelButton            matlab.ui.control.Button
        InvertButton                  matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        Panel_2                       matlab.ui.container.Panel
        Clear                         matlab.ui.control.Button
        Bchannel                      matlab.ui.control.Slider
        BButton                       matlab.ui.control.Button
        Gchannel                      matlab.ui.control.Slider
        GButton                       matlab.ui.control.Button
        Rchannel                      matlab.ui.control.Slider
        RButton                       matlab.ui.control.Button
        Output                        matlab.ui.control.UIAxes
        GrayScale                     matlab.ui.control.UIAxes
        Image                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img % Description
        current_img
        gray_img;
        kernel;
        width;
        height;
        dim;
    end
    
    methods (Access = public)
        
        function [] = update_img(app,local_img)
            imshow(local_img,'Parent',app.Output);
        end
        
        function [] = getKernel(app,k)
            app.kernel = k;
        end
        
        
        function [] = updateChannel(app,local_img,increment,order)
            Channel = local_img(:,:,order);
            Channel = Channel + increment;
            local_img(:,:,order) = Channel;
            app.current_img = local_img;
            app.update_img(local_img);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app)
            app.ImageFilterUIFigure.Visible = 'off';
            movegui(app.ImageFilterUIFigure,"center")
            app.ImageFilterUIFigure.Visible = 'on';
            
            app.img = imread("figures\chess.png");
            app.gray_img = rgb2gray(app.img);
            app.current_img = app.img;
            imshow(app.img,'Parent',app.Image);
            imshow(app.gray_img,'Parent',app.GrayScale);
            imshow(app.img,'Parent',app.Output);
            
            app.kernel = zeros(3);
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            local_img = 255 - app.img;
            app.update_img(local_img);
        end

        % Button pushed function: SelectKernelButton
        function SelectKernelButtonPushed(app, event)
            updateKernel(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            local_img = app.gray_img;
            [app.height, app.width ] = size(local_img);
            
            kwidth = 1;     %floor(size(app.kernel,1)/2)
            new_width = app.width+2*kwidth;
            new_height = app.height+2*kwidth; %kernel is square matrix
            output = zeros(new_height,new_width);
            
            local_img = cast(local_img,'double');

            for i = 1+kwidth:app.height-1
                for j = 1+kwidth:app.width-1
                    output(i,j) = sum(local_img(i-kwidth:i+kwidth,j-kwidth:j+kwidth).*app.kernel(:,:),"all");
                end
            end

            output = cast(output,'uint8');
            app.update_img(output);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            img_filename = imgetfile();
            %keep the app window on top
            drawnow
            figure(app.ImageFilterUIFigure);
            
            app.img = imread(img_filename);
            app.gray_img = rgb2gray(app.img);
            app.current_img = app.img;
            imshow(app.img,'Parent',app.Image);
            imshow(app.gray_img,'Parent',app.GrayScale);
            imshow(app.img,'Parent',app.Output);
        end

        % Button pushed function: SepiaButton
        function SepiaButtonPushed(app, event)
            output = app.img;
            output(:,:,1) = app.img(:,:,1)*0.393 + app.img(:,:,2)*0.769 + app.img(:,:,3)*0.189;
            output(:,:,2) = app.img(:,:,1)*0.349 + app.img(:,:,2)*0.686 + app.img(:,:,3)*0.168;
            output(:,:,3) = app.img(:,:,1)*0.272 + app.img(:,:,2)*0.534 + app.img(:,:,3)*0.131;
            app.update_img(output);
        
        end

        % Button pushed function: BinarizeButton
        function BinarizeButtonPushed(app, event)
                thres = app.ThresholdValue.Value;
                output = app.gray_img;
                output(output>=thres) = 255;
                output(output<thres) = 0;
                app.update_img(output);
        end

        % Value changing function: Rchannel
        function RchannelValueChanging(app, event)
            val = event.Value;
            increment = (val - 100)/2.55;
            app.updateChannel(app.current_img,increment,1);
        end

        % Value changing function: Gchannel
        function GchannelValueChanging(app, event)
            threshold = event.Value;
            increment = (threshold - 100)/2.55;
            app.updateChannel(app.current_img,increment,2);
        end

        % Value changing function: Bchannel
        function BchannelValueChanging(app, event)
            threshold = event.Value;
            increment = (threshold - 100)/2.55;
            app.updateChannel(app.current_img,increment,3);
        end

        % Button pushed function: ResetImageButton
        function ResetImageButtonPushed(app, event)
            imshow(app.img,'Parent',app.Output);
            app.current_img = app.img;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            app.Gchannel.Value = 100;
            app.Rchannel.Value = 100;
            app.Bchannel.Value = 100;
            app.ResetImageButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageFilterUIFigure = uifigure('Visible', 'off');
            app.ImageFilterUIFigure.Name = 'Image Filter';
            app.Image = uiaxes(app.ImageFilterUIFigure);
            app.Image.XTickLabel = '';
            app.Image.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Image.YTickLabel = '';
            app.GrayScale = uiaxes(app.ImageFilterUIFigure);
            app.GrayScale.XTickLabel = '';
            app.GrayScale.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GrayScale.YTickLabel = '';
            app.Output = uiaxes(app.ImageFilterUIFigure);
            app.Output.XTickLabel = '';
            app.Output.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Output.YTickLabel = '';
            app.Panel_2 = uipanel(app.ImageFilterUIFigure);
            app.RButton = uibutton(app.Panel_2, 'push');
            app.RButton.Text = 'R';
            app.Rchannel = uislider(app.Panel_2);
            app.Rchannel.Limits = [0 200];
            app.Rchannel.ValueChangingFcn = createCallbackFcn(app, @RchannelValueChanging, true);
            app.Rchannel.Value = 100;
            app.GButton = uibutton(app.Panel_2, 'push');
            app.GButton.Text = 'G';
            app.Gchannel = uislider(app.Panel_2);
            app.Gchannel.Limits = [0 200];
            app.Gchannel.ValueChangingFcn = createCallbackFcn(app, @GchannelValueChanging, true);
            app.Gchannel.Value = 100;
            app.BButton = uibutton(app.Panel_2, 'push');
            app.BButton.Text = 'B';
            app.Bchannel = uislider(app.Panel_2);
            app.Bchannel.Limits = [0 200];
            app.Bchannel.ValueChangingFcn = createCallbackFcn(app, @BchannelValueChanging, true);
            app.Bchannel.Value = 100;
            app.Clear = uibutton(app.Panel_2, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear';
            app.Panel = uipanel(app.ImageFilterUIFigure);
            app.InvertButton = uibutton(app.ImageFilterUIFigure, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Text = 'Invert';
            app.SelectKernelButton = uibutton(app.ImageFilterUIFigure, 'push');
            app.SelectKernelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectKernelButtonPushed, true);
            app.SelectKernelButton.Text = 'Select Kernel';
            app.ApplyButton = uibutton(app.ImageFilterUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.LoadImageButton = uibutton(app.ImageFilterUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SepiaButton = uibutton(app.ImageFilterUIFigure, 'push');
            app.SepiaButton.ButtonPushedFcn = createCallbackFcn(app, @SepiaButtonPushed, true);
            app.SepiaButton.Text = 'Sepia';
            app.BinarizeButton = uibutton(app.ImageFilterUIFigure, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizeButton.Text = 'Binarize';
            app.ThresholdValueEditFieldLabel = uilabel(app.ImageFilterUIFigure);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ResetImageButton = uibutton(app.ImageFilterUIFigure, 'push');
            app.ResetImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageButtonPushed, true);
            app.ResetImageButton.Text = 'Reset Image';
            app.CloseButton = uibutton(app.ImageFilterUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ThresholdValue = uieditfield(app.ImageFilterUIFigure, 'numeric');
            app.ThresholdValue.Value = 127;
            app.GrayScaleImageLabel = uilabel(app.ImageFilterUIFigure);
            app.GrayScaleImageLabel.Text = 'GrayScale Image';
            app.InputImageLabel = uilabel(app.ImageFilterUIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.FilteredImageLabel = uilabel(app.ImageFilterUIFigure);
            app.FilteredImageLabel.Text = 'Filtered Image';
            % Show the figure after all components are created
            app.ImageFilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = img_convolution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageFilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageFilterUIFigure)
        end
    end
end