classdef updateKernel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        x3KernelUIFigure    matlab.ui.Figure
        SubmitButton        matlab.ui.control.Button
        closeButton         matlab.ui.control.Button
        StandardImageKernelsButtonGroup  matlab.ui.container.ButtonGroup
        b_3                 matlab.ui.control.NumericEditField
        m_3                 matlab.ui.control.NumericEditField
        t_3                 matlab.ui.control.NumericEditField
        b_2                 matlab.ui.control.NumericEditField
        m_2                 matlab.ui.control.NumericEditField
        t_2                 matlab.ui.control.NumericEditField
        b_1                 matlab.ui.control.NumericEditField
        m_1                 matlab.ui.control.NumericEditField
        t_1                 matlab.ui.control.NumericEditField
        RightSobelButton    matlab.ui.control.ToggleButton
        LeftSobelButton     matlab.ui.control.ToggleButton
        BottomSobelButton   matlab.ui.control.ToggleButton
        TopSobelButton      matlab.ui.control.ToggleButton
        OutlineButton       matlab.ui.control.ToggleButton
        EmbossButton        matlab.ui.control.ToggleButton
        GuassianBlurButton  matlab.ui.control.ToggleButton
        SharpenButton       matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)
        mainApp % Description
    end
    
    methods (Access = public)
        
        function [] = update_num_field(app,vals)
                app.t_1.Value = vals(1);
                app.t_2.Value = vals(2);
                app.t_3.Value = vals(3);
                app.m_1.Value = vals(4);
                app.m_2.Value = vals(5);
                app.m_3.Value = vals(6);
                app.b_1.Value = vals(7);
                app.b_2.Value = vals(8);
                app.b_3.Value = vals(9);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app, mainApp)
            app.x3KernelUIFigure.Visible = 'off';
            movegui(app.x3KernelUIFigure,"center")
            app.x3KernelUIFigure.Visible = 'on';
            app.mainApp = mainApp;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            k = zeros(3);
            k(1,1) = app.t_1.Value;
            k(1,2) = app.t_2.Value;
            k(1,3) = app.t_3.Value;
            k(2,1) = app.m_1.Value;
            k(2,2) = app.m_2.Value;
            k(2,3) = app.m_3.Value;
            k(3,1) = app.b_1.Value;
            k(3,2) = app.b_2.Value;
            k(3,3) = app.b_3.Value;

            getKernel(app.mainApp,k);
        end

        % Callback function
        function UIFigureCloseRequest(app, event)

            
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: 
        % StandardImageKernelsButtonGroup
        function StandardImageKernelsButtonGroupSelectionChanged(app, event)
            operation = app.StandardImageKernelsButtonGroup.SelectedObject.Text;
            if operation == "Sharpen"               
                app.update_num_field([0 -1 0 -1 5 -1 0 -1 0]);
            elseif operation == "Guassian Blur"
                app.update_num_field([1 2 1 2 4 2 1 2 1]./16);
            elseif operation == "Emboss"
                 app.update_num_field([-2 -0 0 -1 1 1 0 1 2]);
            elseif operation == "Outline"
                 app.update_num_field([-1 -1 -1 -1 8 -1 -1 -1 -1]);
            elseif operation == "Top Sobel"
                 app.update_num_field([1 2 1 0 0 0 -1 -2 -1]);
            elseif operation == "Bottom Sobel"
                 app.update_num_field([-1 -2 -1 0 0 0 1 2 1]);
            elseif operation == "Left Sobel"
                 app.update_num_field([1 0 -1 2 0 -2 1 0 -1]);
            elseif operation == "Right Sobel"
                 app.update_num_field([-1 0 1 -2 0 2 -1 0 1]);      
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.x3KernelUIFigure = uifigure('Visible', 'off');
            app.x3KernelUIFigure.Name = '(3x3) Kernel';
            app.StandardImageKernelsButtonGroup = uibuttongroup(app.x3KernelUIFigure);
            app.StandardImageKernelsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StandardImageKernelsButtonGroupSelectionChanged, true);
            app.StandardImageKernelsButtonGroup.Title = 'Standard Image Kernels';
            app.SharpenButton = uitogglebutton(app.StandardImageKernelsButtonGroup);
            app.SharpenButton.Text = 'Sharpen';
            app.SharpenButton.Value = true;
            app.GuassianBlurButton = uitogglebutton(app.StandardImageKernelsButtonGroup);
            app.GuassianBlurButton.Text = 'Guassian Blur';
            app.EmbossButton = uitogglebutton(app.StandardImageKernelsButtonGroup);
            app.EmbossButton.Text = 'Emboss';
            app.OutlineButton = uitogglebutton(app.StandardImageKernelsButtonGroup);
            app.OutlineButton.Text = 'Outline';
            app.TopSobelButton = uitogglebutton(app.StandardImageKernelsButtonGroup);
            app.TopSobelButton.Text = 'Top Sobel';
            app.BottomSobelButton = uitogglebutton(app.StandardImageKernelsButtonGroup);
            app.BottomSobelButton.Text = 'Bottom Sobel';
            app.LeftSobelButton = uitogglebutton(app.StandardImageKernelsButtonGroup);
            app.LeftSobelButton.Text = 'Left Sobel';
            app.RightSobelButton = uitogglebutton(app.StandardImageKernelsButtonGroup);
            app.RightSobelButton.Text = 'Right Sobel';
            app.t_1 = uieditfield(app.StandardImageKernelsButtonGroup, 'numeric');
            app.m_1 = uieditfield(app.StandardImageKernelsButtonGroup, 'numeric');
            app.m_1.Value = -1;
            app.b_1 = uieditfield(app.StandardImageKernelsButtonGroup, 'numeric');
            app.t_2 = uieditfield(app.StandardImageKernelsButtonGroup, 'numeric');
            app.t_2.Value = -1;
            app.m_2 = uieditfield(app.StandardImageKernelsButtonGroup, 'numeric');
            app.m_2.Value = 5;
            app.b_2 = uieditfield(app.StandardImageKernelsButtonGroup, 'numeric');
            app.b_2.Value = -1;
            app.t_3 = uieditfield(app.StandardImageKernelsButtonGroup, 'numeric');
            app.m_3 = uieditfield(app.StandardImageKernelsButtonGroup, 'numeric');
            app.m_3.Value = -1;
            app.b_3 = uieditfield(app.StandardImageKernelsButtonGroup, 'numeric');
            app.closeButton = uibutton(app.x3KernelUIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            app.SubmitButton = uibutton(app.x3KernelUIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.x3KernelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = updateKernel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.x3KernelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Initialize(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.x3KernelUIFigure)
        end
    end
end